classdef brainSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        surfSegUIFigure           matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        OptionsDropDown           matlab.ui.control.DropDown
        OptionsDropDownLabel      matlab.ui.control.Label
        OutputPathTextArea        matlab.ui.control.TextArea
        OutputPathTextAreaLabel   matlab.ui.control.Label
        MSMHMechEngLabel          matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        EmbeddingsGreyThresholdof255EditField  matlab.ui.control.NumericEditField
        EmbeddingsGreyThresholdof255EditFieldLabel  matlab.ui.control.Label
        PostProcessElementSizepixelsEditField  matlab.ui.control.NumericEditField
        PostProcessElementSizepixelsEditFieldLabel  matlab.ui.control.Label
        UITable_2                 matlab.ui.control.Table
        ScanPathTextArea          matlab.ui.control.TextArea
        ScanPathTextAreaLabel     matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        surfSegHiPCTsurfaceLabel  matlab.ui.control.Label
        RunProgrammeButton        matlab.ui.control.Button
        SelectScanFolderButton    matlab.ui.control.Button
        TimeDelaysEditField       matlab.ui.control.NumericEditField
        TimeDelaysEditFieldLabel  matlab.ui.control.Label
        LayersformanualsegpressenterEditField  matlab.ui.control.NumericEditField
        LayersformanualsegpressenterEditFieldLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        Panel                     matlab.ui.container.Panel
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%% brainSeg.mlapp %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% This app is designed to run a surface segmentation of the brain and
% and produce a nifti file of the whole scan, mask, and brain only.

% This uses the Segment Anything Method (SAM) model built by google &
% the internal SmartInterpol tool.

% Built in MATLAB 2024a (Windows 11), June 2024


% inputs
    % hipct folder of jp2 (JPEG2000) downloaded from ESRF
    % folder path to store niftis
    % variables for the segmentation (leave as default)
        % number of layers
        % post-processing kernel size
        % segmentation/adjusting time delay


% outputs
    % nifti of whole scan (inc. excess)
    % nifti of brain surface mask
    % nifti of brain (exc. excess)


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private) %% Stores all variables for use
        %% segmentation variables
        layerCount % number of manually segmented layers
        timeDelay % delay in seconds for the segmentation adjustments
        fileList % list of files without directory (folderFiles derivitive)
        fileCount % number of files in directory (no. of image slices)
        currentLayer % the current MANUALLY SEGMENTED LAYER (x of layerCount)
        currentSlice % the current IMAGE SLICE (x of fileCount)
        seg_layers % list of manually segmented layers
        approveStatus % status for approve button
        repeatStatus % status for repeat button
        noMaskStatus % status for no mask button
        threshold % value of 255 for preprocessing images
        seSize % structuring elelment size for post-process
        %sam % segment anything model object
       
        
        %% Paths
        folderPath % path to the folder containing JPEG200 files
        folderFiles % list of full file names for all files in folderPath
        maskFolder % folder containing sparce segmentations (jp2)
        inputVolPath % path to input volume nifti (file name)
        sparceMaskPath % path to sparce mask nifit (file name)
        niftiToolboxPath % path to nifit toolbox
        niftiPath % folder to store nifti
        outputFile % output file name for nifti
        niftiSize % size of nifti volume


        %% volumes
        vol % nifit volume of original image
        maskVol_preInterpol % matrix of only manually segmented layers
        maskVol % nifti mask volumes
        brainVol % nifti volume of only brain tissues


    end
    
    methods (Access = private)
        

        function startApp (app)
        %%%%%%%%%%%%%%%%%%%% AppStart %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        optionState = app.OptionsDropDown.Value; % Get the selected value from the dropdown
        
            switch optionState
                case 'Segment and Interpolate'
                    % Run segmentation manually then interpolate
                    runSegmentation(app);
                    runInterpol(app);
        
                case 'Segment Only'
                    % Run segmentation then stop
                    runSegmentation(app);
        

                case 'Bin by 2 Then Segment'
                    % Run segmentation then stop
                    %runSegmentation(app);
        

                case 'Bin by 4 Then Segment'
                    % Run segmentation then stop
                    %runSegmentation(app)

                case 'Interpolate Only'
                    % Run smartInterpol using defined folder
                    runInterpol(app);
                
                case 'Convert to Nifti Only'
                    % Run segmentation then stop
                    
                    inputDir = app.folderPath;
                    OutputFile = fullfile(app.folderPath, '/NIFTI.nii');
                    
                    hipct2nifti(app, inputDir, OutputFile);
        
                otherwise
                    % Handle unexpected optionState values
                    error('Unexpected option state: %s', optionState);
            end
        end


        function runSegmentation(app)
        %%%%%%%%%%%%%%%%%%%% RunSegmentation %%%%%%%%%%%%%%%%%%%%


        % blurb

            
            %% check values
            checkVars(app)

            %% find manually segmented volume 

            % file spacing
            seg_layers_dec = linspace(1, app.fileCount, app.layerCount + 2); % finds vector of N evenly spaced file incides from 1 to total files in directory
            seg_layers_dec = seg_layers_dec(2:end-1);            
            app.seg_layers = round(seg_layers_dec); % rounds all to nearest integer value


            %% make output nifti folder
            % Define the prefix to add
            prefix = 'nfiti_';
            
            % Extract the parent directory & existing folder name
            [parentDir, existingFolderName, ext] = fileparts(app.folderPath);
            
            % Create the new folder name by adding the prefix
            newFolderName = [prefix existingFolderName];
            
            % Create the full path for the new directory
            newFolderPath = fullfile(parentDir, newFolderName);
            
            % Create the new directory
            status = mkdir(newFolderPath);

            % folder to hold nifti volumes
            app.niftiPath = newFolderPath;

            % show path
            app.OutputPathTextArea.Value = app.niftiPath;


            %% run segmentation function (segVol)
            segVol(app);


            %% message

            msgbox('Segmentation complete, please wait while nifits are created.');

         
            %% make mask and input image niftis

            % input
            app.inputVolPath = fullfile(app.niftiPath, '/inputVol.nii');
            hipct2nifti(app, app.folderPath, app.inputVolPath)

            % mask (sparce)
            app.sparceMaskPath = fullfile(app.niftiPath, '/sparceMask.nii');
            hipct2nifti(app, app.maskFolder, app.sparceMaskPath);


            %% close and finish

            msgbox(['Nifti files can be found in:' app.niftiPath] );
     

        end 


        function runInterpol(app)
        %%%%%%%%%%%%%%%%%%%% RunInterpol %%%%%%%%%%%%%%%%%%%%

        % This function runs the smartInterpol tool on the niftis produced
        % by segmentation
            
        % inputs
            % inputVol.nii.gz
            % brainMask_preInterpol.nii.gz

        % outputs
            % brainMask.nii.gz
            % brainOnly_FILENAME.nii.gz


        %% find niftis

        app.niftiPath = app.OutputPathTextArea.Value;
        app.inputVolPath = fullfile(app.niftiPath, '/inputVol.nii.gz');
        app.sparceMaskPath = fullfile(app.niftiPath, '/sparceMask.nii.gz');


        %% set up paths and variables

        im_vol = char(app.inputVolPath)
        lab_vol = char(app.sparceMaskPath)
        result_path = char(app.niftiPath);
        trained_net_file = './global_weights.mat'; % find trained model

        %% run smartInterpol
       
         
        SmartInterpol(im_vol, ... % input scan nifti
            lab_vol, ... % sparce labelled mask nifti
            result_path, ... % results
            trained_net_file, ... % weights 
            'firstSliceOption',1, ... % assume frst slice not segmented
            'lastSliceOption',1, ... % assume last slice not segmented
            'maxepoch_global', 100, ... 
            'maxepoch_local',50);


        %% end and produce message


        end 


        function vol = hipct2nifti(~, inputDir, outputFile)
        %% %%%%%%%%%%%%%%%%% hipct2nifti function %%%%%%%%%%%%%%%%%%%%%%%%
            
            % inputs
                % inputDir - directory of jp2 images (from esrf globus)
                % outputFile - nifti file name (.nii) for output
                %   [also saves a compressed .nii.gz]
                
            % outputs 
                % (nifti files saved to outputFile location)
                % Vol - nifti saved to matlab workspace 
        
            % Add path to NIfTI toolbox (adjust the path according to your installation)
            addpath('C:\Users\Andrew\AppData\Roaming\MathWorks\MATLAB Add-Ons\Collections\Tools for NIfTI and ANALYZE image');
        
            % Get a list of all JPEG2000 files in the directory
            jpegFiles = dir(fullfile(inputDir, '*.jp2'));
            
            % Read the first image to get the dimensions
            firstImage = imread(fullfile(inputDir, jpegFiles(1).name));
            [rows, cols] = size(firstImage);
            
            % Initialize a 3D volume to store all images
            numImages = length(jpegFiles);
            volume = zeros(rows, cols, numImages, 'like', firstImage);
            
            % Loop through each JPEG2000 file and read the image
            for i = 1:numImages
                fileName = fullfile(inputDir, jpegFiles(i).name);
                volume(:,:,i) = imread(fileName);
            end
            
            % Create a NIfTI structure
            vol = make_nii(volume);
            
            % Save the NIfTI file
            save_nii(vol, outputFile);
        
            fprintf('NIfTI file saved successfully to %s\n', outputFile);
        
            % create compressed .nii.gz
            gzip(outputFile);
        
        end
        
        
        function convertImagesToBinaryMask(~,inputDir)
            %% convertImagesToBinaryMask function
            % Converts all images in a folder to binary masks and replaces the original files.
            %
            % inputs:
            %   inputDir  - directory of input images
            %
            % outputs:
            %   Binary mask images saved in the same directory, replacing the original files
        
            % Get a list of all JPEG2000 files in the input directory
            jpegFiles = dir(fullfile(inputDir, '*.jp2'));
            
            if isempty(jpegFiles)
                error('No JPEG2000 files found in the specified directory.');
            end
        
            % Loop through each JPEG2000 file
            for i = 1:length(jpegFiles)
                % Read the image
                inputFileName = fullfile(inputDir, jpegFiles(i).name);
                image = imread(inputFileName);
                
                % Check if the image is color (3 channels)
                [rows, cols, channels] = size(image);
                if channels > 1
                    % Convert 3-channel logical image to a single channel binary image
                    binaryMask = image(:,:,1) | image(:,:,2) | image(:,:,3);
                else
                    % Convert to binary mask (any non-zero value becomes true)
                    binaryMask = image >= 1;
                end
        
                % Save the binary mask image, replacing the original file
                imwrite(uint8(binaryMask) * 255, inputFileName); % Convert binary mask to uint8 for saving
                
                fprintf('Processed and saved binary mask: %s\n', inputFileName);
            end
        
            fprintf('All images have been processed and replaced in %s\n', inputDir);
        end


        function checkVars(app)
        %% %%%%%%%%% checks all variables from app inputs %%%%%%%%%%%%%%%%
            
            % inputs 
                % app (pulls all for this app, brainSeg)
            % outputs
                % N/A

            % Check layer values

            app.folderFiles = dir(fullfile(app.folderPath, '*.jp2'));
            app.fileList = app.folderFiles(~[app.folderFiles.isdir]); % files without dir
            app.ScanPathTextArea.Value = app.folderPath;


            % Correctly count the number of .gz files
            app.fileCount = length(app.folderFiles)


            app.UITable.Data(1,2) = app.fileCount;
            app.UITable.Data(1,1) = 1;
            app.UITable_2.Data(1,1) = 1;


            %
            app.layerCount = app.LayersformanualsegpressenterEditField.Value;
            app.UITable_2.Data(1,2) = app.layerCount; 

            % view layer counts
            app.layerCount = app.LayersformanualsegpressenterEditField.Value;
            app.UITable_2.Data(1,2) = app.layerCount; 

            % check time delay
            app.timeDelay = app.TimeDelaysEditField.Value;
            
            % check se size
            app.seSize = app.PostProcessElementSizepixelsEditField.Value;

            app.threshold = app.EmbeddingsGreyThresholdof255EditField.Value;


        end


        function segVol(app)
        %%%%%%%%%%%%%%%%%%%%%%%%%%% SegVol function %%%%%%%%%%%%%%%%%%%%%%%

        % function to run manual segmentation on a volume and its folder
        % of jp2 


            % inputs
                % vol - full scan nifti 
                % folderPath - path to full scan jp2 files (from esrf)

            % outputs
                % maskVol_preInterpol - mask volume containing only
                % manually segmented layers


            %% Intialise SAM
            sam = segmentAnythingModel;


            %% make mask iamge folder
            % Define the prefix to add
            prefix = 'masked_';
            
            % Extract the parent directory and the existing folder name
            [parentDir, existingFolderName, ext] = fileparts(app.folderPath);
            
            % Create the new folder name by adding the prefix
            newFolderName = [prefix existingFolderName];
            
            % Create the full path for the new directory
            newFolderPath = fullfile(parentDir, newFolderName);
            
            % Create the new directory
            status = mkdir(newFolderPath);

            app.maskFolder = newFolderPath;


            %% start for loop to progress through individual layers
            for iter = 1:app.layerCount
            
            % Clear values for approval status for new segmentation
            app.noMaskStatus = false;
            
            % slice info
            app.currentLayer = iter; % N out of total manual segmentations
            app.currentSlice = app.seg_layers(app.currentLayer); % N out of total image slices
              
            % update the values in the text area
            app.UITable.Data(1,1) = app.currentSlice;
            app.UITable_2.Data(1,1) = app.currentLayer;

            % find image from folder
            imFile = app.fileList(app.currentSlice); % find file from director
            imPath = fullfile(imFile.folder, imFile.name); % full file name
            
            % read images
            Im_raw = imread(imPath);
            imageSize = size(Im_raw);  % find image dimensions
            
             
            %% Preproccessing 
            % remove extremes due to blood
            testMed = medfilt2(Im_raw, [6 6]); %median filtering
                
            % normalise greyscale to 8 bits
            testIm = im2uint8(mat2gray(testMed)); % normalize, cast and scale
            [min(testIm(:)) max(testIm(:))]; % show the actual data range
            
            % threshold grey level of image to remove most surrounding gel
            threshold = app.EmbeddingsGreyThresholdof255EditField.Value; % can be changed
            testIm(testIm<threshold) = 0; 
                          
            % extract embeddings from image 
            embeddings = extractEmbeddings(sam,testIm);
            
            %% Segment using image embeddings (interactive) 
            % time to draw bounding box around tissues
            
            % show image and roi within panel axes
            p = app.Panel;
            hAX = axes(p);
                    
            hIm = imshow(Im_raw,Parent=hAX);
           
            %%%%% ?????????????? wait or approve 
            
            %% IF NO BRAIN VISIBLE (button press, no bounding box)
            if app.noMaskStatus == true 
                mask = false(imageSize);
                imMask = mask;
                displayMask(hIm,Im_raw,imMask);

                app.maskVol_preInterpol(:,:,app.currentSlice) = imMask(:,:,1);
                % Save, skips approvals

            else
                    %% Run Segmentation function
                    
                    % inputs not already defined within this script 
                    threshold = app.threshold;
                    timeDelay = app.timeDelay;
                    Panel = app.Panel;
                    seSize = app.seSize;

                    % call function 
                    imMask = imSeg(app, sam, imPath, threshold, Panel, timeDelay, seSize);
        
                    %% Wait for Approval or Redo or No Brain 

                    app.maskVol_preInterpol(:,:,app.currentSlice) = imMask(:,:,1);
        
                        
                        % Generate the indexed filename
                        indexedFilename = sprintf('%06d.jp2', app.currentSlice); % Format index as ex. "000001.jp2"
                        fullFilePath = fullfile(newFolderPath, indexedFilename);
                        
                        % Save the mask image as a JP2 file
                        imwrite(imMask, fullFilePath, 'jp2');
                        
                        % Check if the file was saved successfully
                        if exist(fullFilePath, 'file')
                            fprintf('Mask image saved successfully as "%s".\n', fullFilePath);
                        else
                            fprintf('Failed to save mask image as "%s".\n', fullFilePath);
                        end


            end %% end approval if, clear panel and variables for next loop
          
  
            % clear panel before next loop 
            cla(app.Panel);

                
            end % end for loop of seg_layers

            %% fill empty mask slides as blank

            sliceCount = app.fileCount

            
            % Initialize a vector of true values with length equal to the number of files
            slicesList = true(1, sliceCount); 
            
            % Assuming seg_layers contains indices that should be set to false
            manualSlices = false(1, sliceCount); 
            
            % Set the indices in seg_layers to false
            manualSlices(app.seg_layers) = true;
            
            % Combine slicesList and manualSlices to get the final emptySlices
            emptySlices = slicesList & ~manualSlices;


            for int = 1:sliceCount
                
                if emptySlices(int) == true
                        imMask(:,:,1) = false(size(testIm));
                        
                        % Generate the indexed filename
                        indexedFilename = sprintf('%06d.jp2', int); % Format index as ex. "000001.jp2"
                        fullFilePath = fullfile(newFolderPath, indexedFilename);
                        
                        % Save the mask image as a JP2 file
                        imwrite(imMask(:,:,1), fullFilePath, 'jp2');
                        
                end

            end

            
        %% convert from rgb mask to binary
        convertImagesToBinaryMask(app, newFolderPath);


        end % end function
        

        function imMask = imSeg(~, sam, imPath, threshold, Panel, timeDelay, seSize)
            %%%%%%%%%%%%%%%% imSeg function %%%%%%%%%%%%%%%%%%%%%%%%%%%


            % segments an individual image using SAM
            
            % inputs
                % vol - volume of full brain scan
                % folderPath - path to full brain scan jp2 files 

            % outputs
                % brainMask - single slice segmented mask image


            %% find image from folder
                    % read images
                    Im_raw = imread(imPath);
                    imageSize = size(Im_raw);  % find image dimensions
                    
                     
                    %% Preproccessing 
                    % remove extremes due to blood
                    testMed = medfilt2(Im_raw, [6 6]); %median filtering
                        
                    % normalise greyscale to 8 bits
                    testIm = im2uint8(mat2gray(testMed)); % normalize, cast and scale
                    [min(testIm(:)) max(testIm(:))]; % show the actual data range
                    
                    % threshold grey level of image to remove most surrounding gel
                    testIm(testIm<threshold) = 0; 
                                  
                    % extract embeddings from image 
                    embeddings = extractEmbeddings(sam,testIm);
                    
                    %% Segment using image embeddings (interactive) 
                    % time to draw bounding box around tissues
                        % Time to draw bounding box around tissues
                        
                        cla(Panel);
                        
                        % Show image and roi within panel axes
                        p = Panel;
                        hAX = axes(p);
                        
                        hIm = imshow(Im_raw, 'Parent', hAX);
                        
                        validROI = false;
                        
                        while ~validROI
                            roi = drawrectangle(hAX);
                            
                            % Ensure BoundingBox values are positive and within the image bounds
                            boundingBox = roi.Position;
                            boundingBox(1) = max(boundingBox(1), 1);
                            boundingBox(2) = max(boundingBox(2), 1);
                            boundingBox(3) = min(boundingBox(3), imageSize(2) - boundingBox(1) + 1);
                            boundingBox(4) = min(boundingBox(4), imageSize(1) - boundingBox(2) + 1);
                            
                            if all(boundingBox(3:4) > 0) % Check if width and height are positive
                                validROI = true;
                            else
                                delete(roi);
                                msgbox('Invalid ROI. Please draw a new bounding box.');
                            end
                        end


                        % Defines mask from ROI 
                        mask = segmentObjectsFromEmbeddings(sam, embeddings, size(testIm), 'BoundingBox', boundingBox, 'ReturnMultiMask', true);
                        displayMask(hIm, Im_raw, mask);
                        
                        % LISTENER - allows for updates during the timeDelay period
                        listener = addlistener(roi,"ROIMoved",@(src,evt)segmentObjectInROI(evt,sam,embeddings,Im_raw,hIm));
                    
                        % time delay for adjustments
                        pause(timeDelay)
            
                        % post process
                                seClose = strel('disk', seSize);
                                seDilate = strel('disk', 5);
                                closeMask = imclose(mask, seClose);
                                imMask = imdilate(closeMask, seDilate);

                        % show result for decision
                        displayMask(hIm, Im_raw, imMask);

                        % Popup menu for user approval
                        choice = questdlg('Approve the segmentation?', ...
                            'Segmentation Approval', ...
                            'Approve', 'Reject and Restart', 'No Mask', 'Approve');

                        % Handle response
                        switch choice
                            case 'Approve'
                                % Segmentation approved, proceed
                                % Display the final mask
                                displayMask(hIm, Im_raw, imMask);
                                
                            case 'Reject and Restart'
                                % Delete the current ROI and mask, restart segmentation
                                delete(roi);
                                cla(hAX);
                                validROI = false; % This will cause the while loop to restart
                        
                            case 'No Mask'
                                % Create a blank mask
                                imMask = false(size(testIm));
                                displayMask(hIm, Im_raw, imMask);
                        end
                                            

        end

    end


%% %%%%%%%%%%%%%%%%%%% component callback functions %%%%%%%%%%%%%%%%%%%%%%%

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LayersformanualsegpressenterEditField
        function LayersformanualsegpressenterEditFieldValueChanged(app, event)
            app.layerCount = app.LayersformanualsegpressenterEditField.Value;
            app.UITable_2.Data(1,2) = app.layerCount;  
        end

        % Value changed function: TimeDelaysEditField
        function TimeDelaysEditFieldValueChanged(app, event)
            app.timeDelay = app.TimeDelaysEditField.Value;
            
            
        end

        % Button pushed function: SelectScanFolderButton
        function SelectScanFolderButtonPushed(app, event)
            app.folderPath = uigetdir('C:\', "Select folder containing scan");
            app.folderFiles = dir(fullfile(app.folderPath, '*.jp2'));
            app.fileList = app.folderFiles(~[app.folderFiles.isdir]); % files without dir
            app.ScanPathTextArea.Value = app.folderPath;


            % no. of files (images slices)
            app.fileCount = length(app.folderFiles)
            app.UITable.Data(1,2) = app.fileCount;
            app.UITable.Data(1,1) = 1;
            app.UITable_2.Data(1,1) = 1;
            
            % check total number of layers to be segmented
            app.layerCount = app.LayersformanualsegpressenterEditField.Value;
            app.UITable_2.Data(1,2) = app.layerCount;   
    
        end

        % Button pushed function: RunProgrammeButton
        function RunProgrammeButtonPushed(app, event)
        %% %%%%%%%%%%%%%%%% Run segmentation button %%%%%%%%%%%%%%%%%%%%%%%

        startApp(app)
            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.surfSegUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {834, 834};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {316, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.surfSegUIFigure = uifigure('Visible', 'off');
            app.surfSegUIFigure.Name = 'surfSeg';
            app.surfSegUIFigure.Icon = fullfile(pathToMLAPP, 'Examples', 'R2024a', 'TestImages', 'thumbnail.png');
            app.surfSegUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.surfSegUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LayersformanualsegpressenterEditFieldLabel = uilabel(app.LeftPanel);
            app.LayersformanualsegpressenterEditFieldLabel.Text = 'Layers (for manual seg., press enter)';
            app.LayersformanualsegpressenterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LayersformanualsegpressenterEditField.ValueChangedFcn = createCallbackFcn(app, @LayersformanualsegpressenterEditFieldValueChanged, true);
            app.LayersformanualsegpressenterEditField.Value = 6;
            app.TimeDelaysEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeDelaysEditFieldLabel.Text = 'Time Delay (s)';
            app.TimeDelaysEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @TimeDelaysEditFieldValueChanged, true);
            app.TimeDelaysEditField.Value = 10;
            app.SelectScanFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectScanFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectScanFolderButtonPushed, true);
            app.SelectScanFolderButton.Text = ' Select Scan Folder';
            app.RunProgrammeButton = uibutton(app.LeftPanel, 'push');
            app.RunProgrammeButton.ButtonPushedFcn = createCallbackFcn(app, @RunProgrammeButtonPushed, true);
            app.RunProgrammeButton.Text = 'Run Programme';
            app.surfSegHiPCTsurfaceLabel = uilabel(app.LeftPanel);
            app.surfSegHiPCTsurfaceLabel.Text = 'brainSeg (HiPCT surface)';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Current Slice'; 'Total Slices'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'column';
            app.UITable.Multiselect = 'off';
            app.ScanPathTextAreaLabel = uilabel(app.LeftPanel);
            app.ScanPathTextAreaLabel.Text = 'Scan Path';
            app.ScanPathTextArea = uitextarea(app.LeftPanel);
            app.ScanPathTextArea.Editable = 'off';
            app.UITable_2 = uitable(app.LeftPanel);
            app.UITable_2.ColumnName = {'Current Seg. Layer'; 'Total layers'};
            app.UITable_2.RowName = {};
            app.UITable_2.SelectionType = 'column';
            app.UITable_2.Multiselect = 'off';
            app.PostProcessElementSizepixelsEditFieldLabel = uilabel(app.LeftPanel);
            app.PostProcessElementSizepixelsEditFieldLabel.Text = 'Post-Process Element Size (pixels)';
            app.PostProcessElementSizepixelsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PostProcessElementSizepixelsEditField.Value = 20;
            app.EmbeddingsGreyThresholdof255EditFieldLabel = uilabel(app.LeftPanel);
            app.EmbeddingsGreyThresholdof255EditFieldLabel.Text = 'Embeddings Grey Threshold (of 255)';
            app.EmbeddingsGreyThresholdof255EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EmbeddingsGreyThresholdof255EditField.Value = 100;
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_ucl.png');
            app.MSMHMechEngLabel = uilabel(app.LeftPanel);
            app.MSMHMechEngLabel.Text = '(MSM@H, Mech. Eng)';
            app.OutputPathTextAreaLabel = uilabel(app.LeftPanel);
            app.OutputPathTextAreaLabel.Text = 'Output Path';
            app.OutputPathTextArea = uitextarea(app.LeftPanel);
            app.OutputPathTextArea.Editable = 'off';
            app.OutputPathTextArea.Value = {'D:\Andrew\jp2_data\nfiti_169.6um_LADAF-2021-17_brain_complete-organ_pag-0.01_0'};
            app.OptionsDropDownLabel = uilabel(app.LeftPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.LeftPanel);
            app.OptionsDropDown.Items = {'Segment and Interpolate', 'Segment Only', 'Interpolate Only', 'Convert to Nifti Only', 'Bin by 2 Then Segment', 'Bin by 4 Then Segment'};
            app.OptionsDropDown.Value = 'Segment Only';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.ContextMenu = uicontextmenu(app.surfSegUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.surfSegUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainSeg

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.surfSegUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.surfSegUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.surfSegUIFigure)
        end
    end
end